classdef SolarSpecXPSapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LinslopemaxEditField           matlab.ui.control.NumericEditField
        LinslopemaxEditField_2Label    matlab.ui.control.Label
        LinslopeminEditField           matlab.ui.control.NumericEditField
        LinslopeminEditFieldLabel      matlab.ui.control.Label
        SetFitRangeKBDButton           matlab.ui.control.Button
        FileloadedEditField            matlab.ui.control.EditField
        FileloadedEditFieldLabel       matlab.ui.control.Label
        ginputLabel                    matlab.ui.control.Label
        SetFitRangemouseButton         matlab.ui.control.Button
        UniquenessStepEditField        matlab.ui.control.NumericEditField
        StepeVEditFieldLabel           matlab.ui.control.Label
        UniquenessMaxEditField         matlab.ui.control.NumericEditField
        MaxeVEditFieldLabel            matlab.ui.control.Label
        UniquenessMinEditField         matlab.ui.control.NumericEditField
        MineVEditFieldLabel            matlab.ui.control.Label
        GaussianMixEditField           matlab.ui.control.NumericEditField
        Guaussianmix0100GaussianLabel  matlab.ui.control.Label
        PerformFitButton               matlab.ui.control.Button
        UniquenessPlotButton           matlab.ui.control.Button
        FWHMEditField                  matlab.ui.control.NumericEditField
        FWHM10eVEditFieldLabel         matlab.ui.control.Label
        ShirleyBGButton                matlab.ui.control.Button
        SelectInitialPeaksButton       matlab.ui.control.Button
        BEshiftEditField               matlab.ui.control.NumericEditField
        BEshifteVEditFieldLabel        matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        UniquenessAxes                 matlab.ui.control.UIAxes
        FitResultAxes                  matlab.ui.control.UIAxes
        PeakPositionAxes               matlab.ui.control.UIAxes
        ShirleyBGAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        BE                  % Binding energy data
        Intensity           % Intensity data
        Background          % Value of the background
        BSintensity         % Background corrected intensities
        XPeakGuess          % X coordinate of initial peak guess
        YPeakGuess          % Y coordinate of initial peak guess
        NumPeaks            % Number of peaks to fit
        FitBE               % BE vector used for fitting

        minFitBound         % Lower fitting bound
        LowerFitBoundLine   % Line to display lower fitting bound
        maxFitBound         % Upper fitting bound
        UpperFitBoundLine   % Line to display upper fitting bound

        InitialGuessPoints  % Points to display initial peak guesses


    end

    methods (Access = private)

        function funcHandle = GLSfunc(app)
            %c(1) is the peak intensity, c(2) is the mixing ratio, c(3) is
            %the peak BE, c(4) is the FWHM in eV. From https://doi.org/10.1016/j.apsusc.2018.03.190
            GLSfun = @(x,c) c(1)*(1-c(2))*exp(-4*log(2)*((x-c(3)).^2)./(c(4))^2)+c(1)*c(2)./(1+4*((x-c(3)).^2)./(c(4)).^2);
            funcHandle = GLSfun;
        end

        function [PeakArea] = ShirleyRG(input)

            %SHIRLEYRG Function to use to calculate Shirley background for XPS fitting.

            global BEShirley IntensityShirley BackgroundShirley
            % Get values from inputs
            a = input(1);
            b = input(2);

            % Prepare P and Q area variables.
            P = zeros(length(BEShirley),1);
            Q = zeros(length(BEShirley),1);

            % Calculate curremt background substracted intensities
            BSintensity = IntensityShirley - BackgroundShirley;

            % Determine integral of P and Q for all X values
            for Index = 1:1:length(BEShirley)
                P(Index) = trapz(BSintensity(find(BEShirley==max(BEShirley)):Index));
                Q(Index) = trapz(BSintensity(Index:find(BEShirley==min(BEShirley))));
            end

            % Calculate new background
            BackgroundShirley = (a-b).*Q./(P+Q) + b;

            % Value to minimize
            PeakArea = sum(P)+sum(Q);
        end


        function [output] = SingleVoigtPeak(params,x)
            %SingleVoigtPeak Single Voigt peak as sum of Gauss and Lorentzian
            % x is a column vector; params is a 1 by 4 row vector


            output = params(1)*(1-params(2))*exp(-4*log(2)*((x-params(3)).^2)./(params(4))^2)+...
                params(1)*params(2)./(1+4*((x-params(3)).^2)./(params(4)).^2);

        end


        function [output] = MultiVoigtPeak(app,params,x)
            %MultiPeakFunc Function to evaluate multiple Voigt peaks as defined by SinglePeakFunc
            % x is a column vector; params is an n+1 by 4 matrix, where n is the number of peaks
            % The last row of params is for the linear background (first 2 elements)
            %params(n,1) is the peak intensity, params(n,2) is the mixing ratio, params(n,3) is
            %the peak BE, params(n,4) is the FWHM in eV. From https://doi.org/10.1016/j.apsusc.2018.03.190

            output = zeros(length(x),1);
            TempOutput = zeros(length(x),1);

            for i = 1 : size(params,1)
                TempOutput = SinglePeakFunc(x,params(i,:));     % Sum components of all Voigt peaks
                output = output + TempOutput;
            end

            output = output + params(end,1) + params(end,2)*x;  % Add linear background

        end


        function [coefficients,resnorm] = RunPeakFitting(app)

            %Restrict data to fitting range
            app.FitBE = app.BE(app.BE<app.maxFitBound & app.BE>app.minFitBound);
            FitIntensity = app.BSintensity(app.BE<app.maxFitBound & app.BE>app.minFitBound);


            %Estimate constant background from first point
            InitialGuess(app.NumPeaks+1,1) = FitIntensity(1);  %Estimate constant background from first point
            LowerBound(app.NumPeaks+1,1) = -inf;
            UpperBound(app.NumPeaks+1,1) = inf;

            %Estimate linear background slope from first and last points
            InitialGuess(app.NumPeaks+1,2) = (FitIntensity(end) - FitIntensity(1))/length(FitIntensity);
            LowerBound(app.NumPeaks+1,2) = app.LinslopeminEditField.Value;
            UpperBound(app.NumPeaks+1,2) = app.LinslopemaxEditField.Value;

            %Fill out InitialGuess and Bound matrices
            InitialGuess(app.NumPeaks+1,3:4) = 0;
            LowerBound(app.NumPeaks+1,3:4) = 0;
            UpperBound(app.NumPeaks+1,3:4) = 0;

            %% Construct initial guesses from selected peaks
            for i = 1:1:app.NumPeaks
                InitialGuess(i,1) = app.YPeakGuess(i);
                InitialGuess(i,3) = app.XPeakGuess(i);
            end

            InitialGuess(1:app.NumPeaks,2) = app.GaussianMixEditField.Value;
            InitialGuess(1:app.NumPeaks,4) = app.FWHMEditField.Value;

            LowerBound(1:app.NumPeaks,1) = min(app.Intensity);
            LowerBound(1:app.NumPeaks,2) = app.GaussianMixEditField.Value;
            LowerBound(1:app.NumPeaks,3) = app.minFitBound;
            LowerBound(1:app.NumPeaks,4) = app.FWHMEditField.Value*0.9;

            UpperBound(1:app.NumPeaks,1) = max(app.Intensity);
            UpperBound(1:app.NumPeaks,2) = app.GaussianMixEditField.Value;
            UpperBound(1:app.NumPeaks,3) = app.maxFitBound;
            UpperBound(1:app.NumPeaks,4) = app.FWHMEditField.Value*1.1;

            %% Perform the fitting
            % Define fit options
            opts = statset('Display','iter');

            % Now the next line is where the actual model computation is done.
            [coefficients,resnorm] = lsqcurvefit(@(x,params) app.MultiVoigtPeak(x,params),...
                InitialGuess,app.FitBE,FitIntensity,LowerBound,UpperBound);
        end

        function [coefficients,resnorm] = UniquenessFitting(app,FWHM)

            %Restrict data to fitting range
            UniqueBE = app.BE(app.BE<app.maxFitBound & app.BE>app.minFitBound);
            UniqueIntensity = app.BSintensity(app.BE<app.maxFitBound & app.BE>app.minFitBound);

            %Estimate constant background from first point
            InitialGuess(app.NumPeaks+1,1) = UniqueIntensity(1);  %Estimate constant background from first point
            LowerBound(app.NumPeaks+1,1) = -inf;
            UpperBound(app.NumPeaks+1,1) = inf;

            %Estimate linear background slope from first and last points
            InitialGuess(app.NumPeaks+1,2) = (UniqueIntensity(end) - UniqueIntensity(1))/length(UniqueIntensity);
            LowerBound(app.NumPeaks+1,2) = app.LinslopeminEditField.Value;
            UpperBound(app.NumPeaks+1,2) = app.LinslopemaxEditField.Value;

            %Fill out InitialGuess and Bound matrices
            InitialGuess(app.NumPeaks+1,3:4) = 0;
            LowerBound(app.NumPeaks+1,3:4) = 0;
            UpperBound(app.NumPeaks+1,3:4) = 0;

            %% Construct initial guesses from selected peaks
            for i = 1:1:app.NumPeaks
                InitialGuess(i,1) = app.YPeakGuess(i);
                InitialGuess(i,3) = app.XPeakGuess(i);
            end

            InitialGuess(1:app.NumPeaks,2) = app.GaussianMixEditField.Value;
            InitialGuess(1:app.NumPeaks,4) = FWHM;

            LowerBound(1:app.NumPeaks,1) = min(app.Intensity);
            LowerBound(1:app.NumPeaks,2) = app.GaussianMixEditField.Value;
            LowerBound(1:app.NumPeaks,3) = app.minFitBound;
            LowerBound(1:app.NumPeaks,4) = FWHM*0.9;

            UpperBound(1:app.NumPeaks,1) = max(app.Intensity);
            UpperBound(1:app.NumPeaks,2) = app.GaussianMixEditField.Value;
            UpperBound(1:app.NumPeaks,3) = app.maxFitBound;
            UpperBound(1:app.NumPeaks,4) = FWHM*1.1;

            %% Perform the fitting
            % Now the next line is where the actual model computation is done.
            [coefficients,resnorm] = lsqcurvefit(@(x,params) app.MultiVoigtPeak(x,params),...
                InitialGuess,UniqueBE,UniqueIntensity,LowerBound,UpperBound);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %% Select the data file to analyze
            [file,path] = uigetfile('.ascii');

            FullFileName = [path, file];
            app.FileloadedEditField.Value = file;

            %% Setup the Import Options and import the data
            opts = delimitedTextImportOptions("NumVariables", 4);
            opts.Delimiter = "\t";
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            opts.VariableTypes = ["string", "double", "double", "string"];

            %% Import the data and select the key ones
            FullTable = readtable(FullFileName,opts);

            app.BE = FullTable.Var2(5:end-2);
            app.Intensity = FullTable.Var3(5:end-2);

            % Clear temporary variables
            clear FullTable opts

            %% Apply any shifts needed.
            app.BE = app.BE + app.BEshiftEditField.Value;

            %% Hide/clear plots until appropriate to work on them
            app.PeakPositionAxes.Visible = 'off';
            app.UniquenessAxes.Visible = 'off';
            app.FitResultAxes.Visible = 'off';

            delete(app.PeakPositionAxes.Children);
            delete(app.UniquenessAxes.Children);
            delete(app.FitResultAxes.Children);

            %% Plot data
            plot(app.ShirleyBGAxes, app.BE, app.Intensity);

        end

        % Button pushed function: ShirleyBGButton
        function BGButtonPushed(app, event)
            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')

            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

            app.ginputLabel.Text = 'You are now selecting the Shirley BG points. Press ESC twice for a linear background.';
            app.ginputLabel.Visible = 'on';             % Give info to user
            [BoundX,~,button] = ginput(2);              % run ginput
            app.ginputLabel.Visible = 'off';             % Hide message

            app.UIFigure.HandleVisibility = fhv;        % return original state

            %% Determine Shirley background

            if isempty(button) == 0
                global BEShirley IntensityShirley BackgroundShirley
                disp('Starting Shirley background fit...')

                LowShirleyBound = min(BoundX);
                HighShirleyBound = max(BoundX);
                [ShirleyIndex,~] = find(app.BE(:,1) >= LowShirleyBound & app.BE(:,1) < HighShirleyBound);

                BEShirley = app.BE(ShirleyIndex);
                IntensityShirley = app.Intensity(ShirleyIndex);

                a0 = IntensityShirley(BEShirley==max(BEShirley));   %Find initial background value at highest BE
                b0 = IntensityShirley(BEShirley==min(BEShirley));   %Find initial background value at lowest BE

                BackgroundShirley = repmat(IntensityShirley(BEShirley==min(BEShirley)),length(BEShirley),1);  %Get initial background from constant value

                % Determine Shirley background by minimizing the peak areas.
                [BestFitValues,exitflag,output] = fminsearch(@ShirleyRG,[a0 b0]);

                app.Background = [repmat(BackgroundShirley(1),ShirleyIndex(1)-1,1);...
                    BackgroundShirley; repmat(BackgroundShirley(end),length(app.BE) - ShirleyIndex(end),1)];

                app.BSintensity = app.Intensity - app.Background;   % Determine background corrected intensities

                disp('... fit complete!')

            else
                disp('No Shirley background applied.')
                app.Background = zeros(size(app.Intensity));
                app.BSintensity = app.Intensity - app.Background;
            end

            % Add background line to plot
            plot(app.ShirleyBGAxes,app.BE,app.Intensity);
            hold(app.ShirleyBGAxes,'on');
            plot(app.ShirleyBGAxes,app.BE,app.Background);
            hold(app.ShirleyBGAxes,'off');

            % Make next plot visible
            app.PeakPositionAxes.Visible = 'on';
            plot(app.PeakPositionAxes, app.BE, app.Intensity);

        end

        % Button pushed function: SetFitRangemouseButton
        function SetFitRangemouseButtonPushed(app, event)
            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')

            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

            app.ginputLabel.Text = 'You are now selecting the fitting range.';
            app.ginputLabel.Visible = 'on';                 % Give info to user
            [FitBoundX,~,button] = ginput(2);           % run ginput
            app.ginputLabel.Visible = 'off';

            app.UIFigure.HandleVisibility = fhv;        % return original state

            % Extract bounds
            app.minFitBound = min(FitBoundX);
            app.maxFitBound = max(FitBoundX);

            % Delete old lines if there
            if app.LowerFitBoundLine == []
                ...
            else
                delete(app.LowerFitBoundLine);
                delete(app.UpperFitBoundLine);
            end

            % Display bounds on plot
            app.LowerFitBoundLine = xline(app.ShirleyBGAxes,app.minFitBound);
            app.UpperFitBoundLine = xline(app.ShirleyBGAxes,app.maxFitBound);


        end

        % Button pushed function: SelectInitialPeaksButton
        function SelectInitialPeaksButtonPushed(app, event)
            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')

            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;            % Current status
            app.UIFigure.HandleVisibility = 'callback';     % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)           % Make fig current

            app.ginputLabel.Text = 'You are now selecting your initial guesses for the peaks.';
            app.ginputLabel.Visible = 'on';                 % Give info to user
            [app.XPeakGuess,app.YPeakGuess,PeakButton] = ginput(5);    % run ginput
            app.ginputLabel.Visible = 'off';                % Hide message

            app.XPeakGuess = app.XPeakGuess(PeakButton==1); % Only get coordinates when mouse was clicked
            app.YPeakGuess = app.YPeakGuess(PeakButton==1);
            app.NumPeaks = length(app.XPeakGuess);

            app.UIFigure.HandleVisibility = fhv;            % return original state

            % Delete old points if there
            if app.InitialGuessPoints == []
                ...
            else
                delete(app.InitialGuessPoints)
            end

            % Add peak guesses to plot
            hold(app.PeakPositionAxes,"on")
            app.InitialGuessPoints = scatter(app.PeakPositionAxes,app.XPeakGuess,app.YPeakGuess,[],'red','filled');
            hold(app.PeakPositionAxes,"off")

            % Make next plots visible
            app.UniquenessAxes.Visible = 'on';
            app.FitResultAxes.Visible = 'on';

        end

        % Button pushed function: PerformFitButton
        function PerformFitButtonPushed(app, event)
            [coefficients,resnorm] = RunPeakFitting(app);

            %% Look at the fit results
            % Evaluate the fit and plot the comparison to the input data
            FitRG = MultiVoigtPeak(app, coefficients, app.FitBE) + app.Background(app.BE<app.maxFitBound & app.BE>app.minFitBound);

            BkgMdl = @(b,x) b(1) + b(2) * x;
            FitBkg = feval(BkgMdl,coefficients(app.NumPeaks+1,1:2),app.FitBE) + app.Background(app.BE<app.maxFitBound & app.BE>app.minFitBound);

            plot(app.FitResultAxes, app.FitBE, FitRG,':','Color',[0.15,0.15,0.15],'Linewidth',2);
            hold(app.FitResultAxes,"on")
            plot(app.FitResultAxes, app.BE, app.Intensity,'Color',[0.64,0.08,0.18],'Linewidth',1.5);
            plot(app.FitResultAxes, app.FitBE, FitBkg, 'Color',[0.50,0.50,0.50],'Linewidth',0.8)

            % Get individual peaks
            for i = 1:1:app.NumPeaks
                GaussFit = SinglePeakFunc(app.FitBE,coefficients(i,:));    % Calculate fit curve
                PeakArea(i) = trapz(GaussFit);                          % Determine area of peak
                GaussFit = GaussFit + FitBkg;                           % Add background for plotting

                switch i        % Change to consistent colors
                    case 1
                        fill(app.FitResultAxes, [app.FitBE' fliplr(app.FitBE')],[GaussFit' fliplr(FitBkg')],[0.47,0.67,0.19],'EdgeColor','none','FaceAlpha',0.5);
                    case 2
                        fill(app.FitResultAxes, [app.FitBE' fliplr(app.FitBE')],[GaussFit' fliplr(FitBkg')],[0.49,0.18,0.56],'EdgeColor','none','FaceAlpha',0.5);
                    case 3
                        fill(app.FitResultAxes, [app.FitBE' fliplr(app.FitBE')],[GaussFit' fliplr(FitBkg')],[0.30,0.75,0.93],'EdgeColor','none','FaceAlpha',0.5);
                    case 4
                        fill(app.FitResultAxes, [app.FitBE' fliplr(app.FitBE')],[GaussFit' fliplr(FitBkg')],[0.64,0.08,0.18],'EdgeColor','none','FaceAlpha',0.5);
                    case 5
                        fill(app.FitResultAxes, [app.FitBE' fliplr(app.FitBE')],[GaussFit' fliplr(FitBkg')],[0.93,0.70,0.10],'EdgeColor','none','FaceAlpha',0.5);
                end
            end

            % Get relative areas
            RelArea = PeakArea./sum(PeakArea)*100;
            
            % Plot residuals
            FitResiduals = FitRG - app.Intensity(app.BE<app.maxFitBound & app.BE>app.minFitBound);
            plot(app.FitResultAxes, app.FitBE,FitResiduals,'-','Color',[0.2 0.2 0.2]);
            plot(app.FitResultAxes, app.FitBE,zeros(length(app.FitBE)),'--','Color',[0.2 0.2 0.2]);

            % Generate legend
            LegendText{1} = 'TotalFit';
            LegendText{2} = 'Data';
            LegendText{3} = 'Background';

            for i = 1:1:app.NumPeaks
                LegendText{3+i} = convertCharsToStrings(['Peak ' num2str(i) ' : ' ...
                    num2str(coefficients(i,3),'%.1f') ' eV; ' num2str(RelArea(i),'%.0f') '%']);
            end
            
            LegendText{end+1} = 'Residuals';
            
            legend(app.FitResultAxes,LegendText,'Location','best','FontSize',12,'FontWeight',"bold")
            legend(app.FitResultAxes,'boxoff')

            hold(app.FitResultAxes,"off")

        end

        % Button pushed function: UniquenessPlotButton
        function UniquenessPlotButtonPushed(app, event)
            UniquenessOutput = [];
            index = 0;
            for testFWHM = app.UniquenessMinEditField.Value: ...
                    app.UniquenessStepEditField.Value: ...
                    app.UniquenessMaxEditField.Value

                index = index + 1;
                [coefficients,resnorm] = UniquenessFitting(app,testFWHM);
                MSE = resnorm/length(app.BE);
                UniquenessOutput(index,1) = testFWHM;
                UniquenessOutput(index,2) = MSE;
            end

            MinMSE = min(UniquenessOutput(:,2));
            FWHMatMinMSE = UniquenessOutput(UniquenessOutput(:,2)==min(UniquenessOutput(:,2)),1);
            
            plot(app.UniquenessAxes,UniquenessOutput(:,1),UniquenessOutput(:,2),'-s')
            text(app.UniquenessAxes,mean(UniquenessOutput(:,1)),max(UniquenessOutput(:,2))*0.8,...
                ['Min @ ' num2str(FWHMatMinMSE) ' eV.'], "FontSize",24);
            
        end

        % Button pushed function: SetFitRangeKBDButton
        function SetFitRangeKBDButtonPushed(app, event)
            
            FitBoundKBD = inputdlg({'What is the lower fitting bound in eV? : ',...
                'What is the upper fitting bound in eV? : '}, 'Choose the fitting bounds');

            % Extract bounds
            FitBoundKBD = str2double(FitBoundKBD);
            app.minFitBound = min(FitBoundKBD);
            app.maxFitBound = max(FitBoundKBD);

            % Delete old lines if there
            if app.LowerFitBoundLine == []
                ...
            else
                delete(app.LowerFitBoundLine);
                delete(app.UpperFitBoundLine);
            end

            % Display bounds on plot
            app.LowerFitBoundLine = xline(app.ShirleyBGAxes,app.minFitBound);
            app.UpperFitBoundLine = xline(app.ShirleyBGAxes,app.maxFitBound);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ShirleyBGAxes = uiaxes(app.UIFigure);
            title(app.ShirleyBGAxes, 'Background')
            xlabel(app.ShirleyBGAxes, 'Binding Energy (eV)')
            ylabel(app.ShirleyBGAxes, 'Intensity')
            zlabel(app.ShirleyBGAxes, 'Z')
            app.ShirleyBGAxes.XDir = 'reverse';
            app.PeakPositionAxes = uiaxes(app.UIFigure);
            title(app.PeakPositionAxes, 'Initial Peak Positions')
            xlabel(app.PeakPositionAxes, 'Binding Energy (eV)')
            ylabel(app.PeakPositionAxes, 'Intensity')
            zlabel(app.PeakPositionAxes, 'Z')
            app.PeakPositionAxes.XDir = 'reverse';
            app.FitResultAxes = uiaxes(app.UIFigure);
            title(app.FitResultAxes, 'Fit Results')
            xlabel(app.FitResultAxes, 'Binding Energy (eV)')
            ylabel(app.FitResultAxes, 'Intensity')
            zlabel(app.FitResultAxes, 'Z')
            app.FitResultAxes.XDir = 'reverse';
            app.UniquenessAxes = uiaxes(app.UIFigure);
            title(app.UniquenessAxes, 'Uniqueness Plot')
            xlabel(app.UniquenessAxes, 'FWHM (eV)')
            ylabel(app.UniquenessAxes, 'Mean Squared Error')
            zlabel(app.UniquenessAxes, 'Z')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.BEshifteVEditFieldLabel = uilabel(app.UIFigure);
            app.BEshifteVEditFieldLabel.Text = 'BE shift (eV)';
            app.BEshiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectInitialPeaksButton = uibutton(app.UIFigure, 'push');
            app.SelectInitialPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInitialPeaksButtonPushed, true);
            app.SelectInitialPeaksButton.Text = 'Select Initial Peaks';
            app.ShirleyBGButton = uibutton(app.UIFigure, 'push');
            app.ShirleyBGButton.ButtonPushedFcn = createCallbackFcn(app, @BGButtonPushed, true);
            app.ShirleyBGButton.Text = 'Set Background';
            app.FWHM10eVEditFieldLabel = uilabel(app.UIFigure);
            app.FWHM10eVEditFieldLabel.Text = 'FWHM ± 10% (eV)';
            app.FWHMEditField = uieditfield(app.UIFigure, 'numeric');
            app.FWHMEditField.Value = 1;
            app.UniquenessPlotButton = uibutton(app.UIFigure, 'push');
            app.UniquenessPlotButton.ButtonPushedFcn = createCallbackFcn(app, @UniquenessPlotButtonPushed, true);
            app.UniquenessPlotButton.Text = 'Generate Uniqueness Plot of FWHM';
            app.PerformFitButton = uibutton(app.UIFigure, 'push');
            app.PerformFitButton.ButtonPushedFcn = createCallbackFcn(app, @PerformFitButtonPushed, true);
            app.PerformFitButton.Text = 'Perform Fit';
            app.Guaussianmix0100GaussianLabel = uilabel(app.UIFigure);
            app.Guaussianmix0100GaussianLabel.Text = {'Guaussian mix'; '(0 = 100% Gaussian)'};
            app.GaussianMixEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaussianMixEditField.Value = 0.1;
            app.MineVEditFieldLabel = uilabel(app.UIFigure);
            app.MineVEditFieldLabel.Text = 'Min (eV)';
            app.UniquenessMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.UniquenessMinEditField.Value = 0.5;
            app.MaxeVEditFieldLabel = uilabel(app.UIFigure);
            app.MaxeVEditFieldLabel.Text = 'Max (eV)';
            app.UniquenessMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.UniquenessMaxEditField.Value = 2;
            app.StepeVEditFieldLabel = uilabel(app.UIFigure);
            app.StepeVEditFieldLabel.Text = 'Step (eV)';
            app.UniquenessStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.UniquenessStepEditField.Value = 0.05;
            app.SetFitRangemouseButton = uibutton(app.UIFigure, 'push');
            app.SetFitRangemouseButton.ButtonPushedFcn = createCallbackFcn(app, @SetFitRangemouseButtonPushed, true);
            app.SetFitRangemouseButton.Text = 'Set Fit. Range (mouse)';
            app.ginputLabel = uilabel(app.UIFigure);
            app.ginputLabel.Visible = 'off';
            app.ginputLabel.Text = 'You are now selecting ***';
            app.FileloadedEditFieldLabel = uilabel(app.UIFigure);
            app.FileloadedEditFieldLabel.Text = 'File loaded';
            app.FileloadedEditField = uieditfield(app.UIFigure, 'text');
            app.SetFitRangeKBDButton = uibutton(app.UIFigure, 'push');
            app.SetFitRangeKBDButton.ButtonPushedFcn = createCallbackFcn(app, @SetFitRangeKBDButtonPushed, true);
            app.SetFitRangeKBDButton.Text = 'Set Fit. Range (KBD)';
            app.LinslopeminEditFieldLabel = uilabel(app.UIFigure);
            app.LinslopeminEditFieldLabel.Text = 'Lin. slope min:';
            app.LinslopeminEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinslopeminEditField.Value = -Inf;
            app.LinslopemaxEditField_2Label = uilabel(app.UIFigure);
            app.LinslopemaxEditField_2Label.Text = 'Lin. slope max:';
            app.LinslopemaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinslopemaxEditField.Value = Inf;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolarSpecXPSapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end